classdef ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CurrentLuxLabel       matlab.ui.control.Label
        TargetLuxLabel        matlab.ui.control.Label
        LogButton             matlab.ui.control.StateButton
        TargetLuxSlider       matlab.ui.control.Slider
        TargetLuxSliderLabel  matlab.ui.control.Label
        PWMAxes               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        s             % Obiekt portu szeregowego
        tmr           % Obiekt Timera
        DataBuffer    % Bufor danych (oś Y)
        TimeBuffer    % Bufor czasu (oś X)
        CurrentPWM    % Przechowuje aktualną wartość PWM
        LogTable      % Tabela do zapisu w Excelu
        TargetBuffer  % Bufor dla wartości zadanej (czerwona linia na wykresie Lux)
        PWMBuffer     % Bufor dla wartości PWM (oś Y drugiego wykresu)
        %PWMAxes      % (Jeśli dodałeś osie ręcznie w Design View, to już tam są)
    end


        methods (Access = private)
         function updateData(app)
    if ~isempty(app.s) && app.s.NumBytesAvailable > 0
        try
            line = readline(app.s);
            
            % --- Obsługa CRC ---
            parts = split(line, '*');
            
            if length(parts) == 2
                raw_data = char(parts(1));          
                received_crc = hex2dec(char(parts(2))); 
                
                if app.crc8_matlab(raw_data) == received_crc
                    % --- Parsowanie danych ---
                    data = sscanf(raw_data, 'Ref:%f, Meas:%f, PWM:%f%%');
                    
                    if length(data) >= 3
                        r_val = data(1); % Cel (Ref)
                        m_val = data(2); % Pomiar (Meas)
                        p_val = data(3); % Sterowanie (PWM)
                        
                        app.CurrentPWM = p_val;

                        % --- LOGOWANIE DO TABELI (Excel) ---
                        if app.LogButton.Value
                            newRow = table(datetime('now'), r_val, m_val, p_val, ...
                                'VariableNames', {'Czas', 'Cel_lx', 'Pomiar_lx', 'PWM_procent'});
                            app.LogTable = [app.LogTable; newRow];
                            app.LogButton.Text = 'REJESTROWANIE...';
                            app.LogButton.BackgroundColor = [1 0.6 0.6]; 
                        else
                            app.LogButton.Text = 'START LOGOWANIA';
                            app.LogButton.BackgroundColor = [0.96 0.96 0.96];
                        end

                        % --- AKTUALIZACJA BUFORÓW ---
                        app.DataBuffer = [app.DataBuffer, m_val];
                        app.TargetBuffer = [app.TargetBuffer, r_val]; % Nowy bufor celu
                        app.PWMBuffer = [app.PWMBuffer, p_val];       % Nowy bufor PWM
                        app.TimeBuffer = [app.TimeBuffer, length(app.DataBuffer)];
                        
                        % Ograniczenie historii do 1000 punktów (dla płynności)
                        % if length(app.DataBuffer) > 1000
                        %     app.DataBuffer(1) = [];
                        %     app.TargetBuffer(1) = [];
                        %     app.PWMBuffer(1) = [];
                        %     app.TimeBuffer(1) = [];
                        % end
                        
                        % --- WYKRES 1: Natężenie światła (UIAxes) ---
                        plot(app.UIAxes, app.TimeBuffer, app.DataBuffer, 'b', 'LineWidth', 2);
                        hold(app.UIAxes, 'on'); 
                        plot(app.UIAxes, app.TimeBuffer, app.TargetBuffer, 'r--', 'LineWidth', 1.5); % Linia celu
                        hold(app.UIAxes, 'off');
                        
                        app.UIAxes.YLim = [0 300]; 
                        app.UIAxes.Title.String = sprintf('Cel: %.1f lx | Aktualne: %.1f lx', r_val, m_val);
                        grid(app.UIAxes, 'on');
                        
                        % --- WYKRES 2: Sterowanie PWM (PWMAxes) ---
                        % Zakładamy, że dodałeś drugie osie o nazwie PWMAxes
                        plot(app.PWMAxes, app.TimeBuffer, app.PWMBuffer, 'Color', [0 0.5 0], 'LineWidth', 1.5);
                        app.PWMAxes.YLim = [-5 105];
                        app.PWMAxes.Title.String = sprintf('Sygnał sterujący PWM: %.1f%%', p_val);
                        grid(app.PWMAxes, 'on');
                        
                        % Synchronizacja osi X dla obu wykresów
                        if ~isempty(app.TimeBuffer)
                            xlims = [min(app.TimeBuffer), max(app.TimeBuffer)];
                            app.UIAxes.XLim = xlims;
                            app.PWMAxes.XLim = xlims;
                        end

                        drawnow limitrate;
                    end
                else
                    fprintf('Błąd CRC! Odrzucono ramkę.\n');
                end
            end
        catch ME
            fprintf('Błąd updateData: %s\n', ME.message);
        end
    end
end
        
        function crc = crc8_matlab(app, data_str)
    crc = uint8(255); % 0xFF
    data_bytes = uint8(data_str);
    for i = 1:length(data_bytes)
        crc = bitxor(crc, data_bytes(i));
        for j = 1:8
            if bitand(crc, uint8(128))
                crc = bitxor(bitshift(crc, 1), uint8(7));
            else
                crc = bitshift(crc, 1);
            end
        end
    end
end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.LogTable = table();        
    app.DataBuffer = [];
    app.TimeBuffer = [];
    app.TargetBuffer = [];
    app.PWMBuffer = [];
    try
        app.s = serialport("COM3", 115200); % Sprawdź czy to na pewno COM3!
        configureTerminator(app.s, "LF");
        flush(app.s);
        disp('Połączono pomyślnie!');
    catch
        uialert(app.UIFigure, 'Nie znaleziono STM32 na COM3', 'Błąd');
    end
    
    % Timer wywołujący funkcję updateData co 0.1 sekundy
    app.tmr = timer('ExecutionMode', 'fixedRate', 'Period', 0.01, ...
                    'TimerFcn', @(~,~) app.updateData());
    start(app.tmr);
            
    % ... twój kod połączenia UART ...
    
    % Inicjalizacja pustej tabeli z nagłówkami
    app.LogTable = table(datetime.empty(0,1), zeros(0,1), zeros(0,1), zeros(0,1), ...
    'VariableNames', {'Czas', 'Cel_lx', 'Pomiar_lx', 'PWM_procent'});
        
        end

        % Value changed function: TargetLuxSlider
        function TargetLuxSliderValueChanged(app, event)
   
    % 1. Sprawdzenie czy obiekt portu istnieje i czy jest połączony
    if ~isempty(app.s) && isvalid(app.s)
        try
            % 2. Pobranie wartości z suwaka
            val = app.TargetLuxSlider.Value;
            
            % 3. Przygotowanie komendy (bez \n, bo writeline doda go sam)
            command = sprintf("s%.1f", val); 
            
            % 4. Wysłanie danych do STM32
            write(app.s, uint8([char(command) 10]), "uint8"); % 10 to kod ASCII dla \n
            
            % 5. Opcjonalne: Wyświetlenie w konsoli MATLAB dla testu
            % fprintf("Wysłano: %s\n", command); 
            
        catch e
            disp("Błąd podczas wysyłania: " + e.message);
        end
    else
        disp("Błąd: Port szeregowy nie jest zainicjalizowany!");
    end


        end

        % Value changed function: LogButton
        function LogButtonValueChanged(app, event)
           
    value = app.LogButton.Value;
    
    % Jeśli właśnie wyłączyliśmy logowanie i mamy jakieś dane
    if value == false && ~isempty(app.LogTable)
        
        % Otwórz okno wyboru miejsca zapisu
        [file, path] = uiputfile('log_pomiaru.xlsx', 'Zapisz dane do Excela');
        
        if isequal(file,0) || isequal(path,0)
            % Użytkownik kliknął Anuluj - nie czyścimy danych, 
            % może będzie chciał zapisać później
        else
            % Pełna ścieżka
            filename = fullfile(path, file);
            
            % Zapisz do Excela
            writetable(app.LogTable, filename);
            
            % Po zapisie czyścimy tabelę, żeby następne logowanie było od zera
            app.LogTable = table(); 
            uialert(app.UIFigure, 'Dane zostały pomyślnie zapisane!', 'Zapis zakończony');
        end
    end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Czas')
            ylabel(app.UIAxes, 'Natężenie [Lx]')
            zlabel(app.UIAxes, 'Z')
            app.PWMAxes = uiaxes(app.UIFigure);
            title(app.PWMAxes, 'Title')
            xlabel(app.PWMAxes, 'Czas')
            ylabel(app.PWMAxes, 'PWM [%]')
            zlabel(app.PWMAxes, 'Z')
            app.TargetLuxSliderLabel = uilabel(app.UIFigure);
            app.TargetLuxSliderLabel.Text = 'Target Lux';
            app.TargetLuxSlider = uislider(app.UIFigure);
            app.TargetLuxSlider.Limits = [0 300];
            app.TargetLuxSlider.ValueChangedFcn = createCallbackFcn(app, @TargetLuxSliderValueChanged, true);
            app.TargetLuxSlider.Value = 7;
            app.LogButton = uibutton(app.UIFigure, 'state');
            app.LogButton.ValueChangedFcn = createCallbackFcn(app, @LogButtonValueChanged, true);
            app.LogButton.Text = 'Start Zapisywania';
            app.TargetLuxLabel = uilabel(app.UIFigure);
            app.TargetLuxLabel.Text = 'Target Lux';
            app.CurrentLuxLabel = uilabel(app.UIFigure);
            app.CurrentLuxLabel.Text = 'Current Lux';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end